classdef Demo_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabAppforLab6UIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        RoomTab                   matlab.ui.container.Tab
        ObjectControlPanel        matlab.ui.container.Panel
        HeightEditField           matlab.ui.control.NumericEditField
        HeightEditFieldLabel      matlab.ui.control.Label
        YEndEditField             matlab.ui.control.NumericEditField
        YEndEditFieldLabel        matlab.ui.control.Label
        YStartEditField           matlab.ui.control.NumericEditField
        YStartEditFieldLabel      matlab.ui.control.Label
        XEndEditField             matlab.ui.control.NumericEditField
        XEndEditFieldLabel        matlab.ui.control.Label
        XStartEditField           matlab.ui.control.NumericEditField
        XStartEditFieldLabel      matlab.ui.control.Label
        GridRoomPanel             matlab.ui.container.Panel
        RoomGridEditField         matlab.ui.control.NumericEditField
        RoomGridEditFieldLabel    matlab.ui.control.Label
        YsizeEditField            matlab.ui.control.NumericEditField
        YsizeEditFieldLabel       matlab.ui.control.Label
        XsizeEditField            matlab.ui.control.NumericEditField
        XsizeEditFieldLabel       matlab.ui.control.Label
        SimulationTab             matlab.ui.container.Tab
        LoadSimButton             matlab.ui.control.Button
        SaveSimButton             matlab.ui.control.Button
        SimulationControlPanel    matlab.ui.container.Panel
        StartButton               matlab.ui.control.Button
        LayersEditField           matlab.ui.control.NumericEditField
        LayersEditFieldLabel      matlab.ui.control.Label
        RaysEditField             matlab.ui.control.NumericEditField
        RaysEditFieldLabel        matlab.ui.control.Label
        ConeAngleEditField        matlab.ui.control.NumericEditField
        ConeAngleEditFieldLabel   matlab.ui.control.Label
        HardwareTab               matlab.ui.container.Tab
        SensorDropDown            matlab.ui.control.DropDown
        SensorDropDownLabel       matlab.ui.control.Label
        LoadScanButton            matlab.ui.control.Button
        SaveScanButton            matlab.ui.control.Button
        ScanControlPanel          matlab.ui.container.Panel
        DelayEditField            matlab.ui.control.NumericEditField
        DelayEditFieldLabel       matlab.ui.control.Label
        StepSizeEditField         matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel    matlab.ui.control.Label
        TiltStopEditField         matlab.ui.control.NumericEditField
        TiltStopEditFieldLabel    matlab.ui.control.Label
        TiltStartEditField        matlab.ui.control.NumericEditField
        TiltStartEditFieldLabel   matlab.ui.control.Label
        PanStopEditField          matlab.ui.control.NumericEditField
        PanStopEditFieldLabel     matlab.ui.control.Label
        PanStartEditField         matlab.ui.control.NumericEditField
        PanStartEditFieldLabel    matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        RunButton                 matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        xsize = 20;
        ysize = 30;
        gridsize = 1;
        xstart = 7;
        xend = 13;
        ystart = 10;
        yend = 20;
        height = 8; % Description
        coneangle = 20;
        rays = 40;
        layers = 3;
        x = [];
        y = [];
        z = [];
        s = 0; % Serial port handle
        panstart = 70;
        panstop = 110;
        tiltstart = 100;
        tiltstop = 116;
        stepsize = 5;
        hwdelay = 150;
        measured_values = zeros(1,7);
        sim_values = zeros(1,7); % Keeping simulation values
        sensorchoice = 'ToF';
    end
    
    methods (Access = private)
        
        function ok = DisplayRoom(app)          % EDIT 1: returns ok
            ok = false;                         % EDIT 2: assume invalid until checks pass
        
            app.xsize = app.XsizeEditField.Value;
            app.ysize = app.YsizeEditField.Value;
            app.gridsize = app.RoomGridEditField.Value;
            app.xstart = app.XStartEditField.Value;
            app.xend = app.XEndEditField.Value;
            app.ystart = app.YStartEditField.Value;
            app.yend = app.YEndEditField.Value;
            app.height = app.HeightEditField.Value;
            app.coneangle = app.ConeAngleEditField.Value;
            app.rays = app.RaysEditField.Value;
            app.layers = app.LayersEditField.Value;
        
            % Grid must leave at least two samples in each direction
            if app.gridsize > app.xsize - 1 || app.gridsize > app.ysize - 1
                uialert(app.MatlabAppforLab6UIFigure, 'Grid size too large for the room.', 'Invalid input');
                return
            end
        
            % Object must sit inside the room, with start <= end
            if app.xstart > app.xend || app.xend > app.xsize || app.ystart > app.yend || app.yend > app.ysize
                uialert(app.MatlabAppforLab6UIFigure, 'Object must fit inside the room, with start <= end.', 'Invalid input');
                return
            end
        
            [app.x, y, z] = RoomGenObject(app.xsize, app.ysize, app.gridsize, ...
                app.xstart, app.xend, app.ystart, app.yend, app.height);
            mesh(app.UIAxes, app.x, y, z);
        
            ok = true;                          % EDIT 3: all checks passed
        end
        
        function RunSim(app)
            % Read and validate & redraws the room
            if ~DisplayRoom(app), return; end
        
            % Disable Start and show progress
            button_text = app.StartButton.Text;
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Running...';
            drawnow;
            progress = uiprogressdlg(app.MatlabAppforLab6UIFigure, 'Title', 'Simulation', ...
                'Message', 'Building room...', 'Indeterminate', 'on');
            cleanup = onCleanup(@() FinishSimulation(app, button_text, progress));
        
            % Collision mesh: delaunay + vertex
            [app.x, app.y, z] = RoomGenObject(app.xsize, app.ysize, app.gridsize, ...
                app.xstart, app.xend, app.ystart, app.yend, app.height);
            faces = delaunay(app.x, app.y);
            vertices = [app.x(:) app.y(:) z(:)];
            vert1 = vertices(faces(:,1), :);
            vert2 = vertices(faces(:,2), :);
            vert3 = vertices(faces(:,3), :);
        
            % Brick scene
            pan_angles    = -25:2:25;
            tilt_angles   = 12:-2:-36;
            bearing_array = [0, 90, 180, -90];
            origin_array  = [4.75 40 19.8; 40 3.1 19.8; 75.25 40 19.8; 40 76.9 19.8];
        
            % Parallel if available, serial otherwise
            use_parallel = ~isempty(ver('parallel')) && license('test', 'Distrib_Computing_Toolbox');
            if use_parallel
                if isempty(gcp('nocreate'))
                    progress.Message = 'Starting parallel pool...';
                    parpool;
                end
                progress.Message = 'Casting rays in parallel...';
            else
                progress.Message = 'Casting rays serially...';
            end
        
            app.sim_values = RunConeScan(vert1, vert2, vert3, origin_array, bearing_array, ...
                pan_angles, tilt_angles, app.coneangle, app.rays, app.layers, use_parallel);
        
            progress.Message = 'Reconstructing the scan...';
            [X, Y, Z] = ReconstructData(app.sim_values);
            mesh(app.UIAxes2, X, Y, Z);
        end
        
        function FinishSimulation(app, button_text, progress)
            % Runs on exit, even after an error
            if isvalid(progress), close(progress); 
            end
            if isvalid(app.StartButton)
                app.StartButton.Enable = 'on';
                app.StartButton.Text = button_text;
            end
        end
        
        function updateScanParams(app)
            app.panstart = app.PanStartEditField.Value;
            app.panstop = app.PanStopEditField.Value;
            app.tiltstart = app.TiltStartEditField.Value;
            app.tiltstop = app.TiltStopEditField.Value;
            app.stepsize = app.StepSizeEditField.Value;
            app.hwdelay = app.DelayEditField.Value;
        
            cmds = sprintf('a%i', app.stepsize);    % step size
            writeline(app.s, cmds);
            cmds = sprintf('c%i', app.panstart);    % pan start
            writeline(app.s, cmds);
            cmds = sprintf('d%i', app.panstop);     % pan stop
            writeline(app.s, cmds);
            cmds = sprintf('e%i', app.tiltstart);   % tilt start
            writeline(app.s, cmds);
            cmds = sprintf('f%i', app.tiltstop);    % tilt stop
            writeline(app.s, cmds);
            cmds = sprintf('h%i', app.hwdelay);     % tilt delay
            writeline(app.s, cmds);
            
            if strcmp(app.sensorchoice, 'ToF')
             writeline(app.s, 'u0');     % ultrasonic off - pulseIn costs 30 ms a point
            else
              writeline(app.s, 'u1');
            end
        end
        
        function sensor_read(app, obj, event)
            data = readline(obj);
            read_values = sscanf(data, '%i');
            if numel(read_values) < 3
                 if contains(data, "SCAN COMPLETE")
                    ShowMeasured(app);
                 end
                 return
            end
            n = obj.UserData.n + 1;
            obj.UserData.n = n;
            obj.UserData.pan_ang(1, n) = read_values(1);
            obj.UserData.tilt_ang(1, n) = read_values(2);
            obj.UserData.distance(1, n) = read_values(3);
            if numel(read_values) >= 4
                 obj.UserData.distance2(1, n) = read_values(4);
            else
                 obj.UserData.distance2(1, n) = 0;
            end
            if mod(n, 5) == 0
             ShowLive(app);
            end
        end
        
        function ShowMeasured(app)
            n_total = app.s.UserData.n;
            if n_total < 1
                return
            end
            app.measured_values = zeros(n_total, 7);
            for n = 1:n_total
                app.measured_values(n, 1) = app.s.UserData.pan_ang(1, n) - 90;
                app.measured_values(n, 2) = app.s.UserData.tilt_ang(1, n) - 100;
                d1 = app.s.UserData.distance(1, n);
                d2 = app.s.UserData.distance2(1, n);
                switch app.sensorchoice
                    case 'ToF'
                        app.measured_values(n, 3) = d1;
                    case 'Ultrasonic'
                        app.measured_values(n, 3) = d2;
                    case 'Both'
                        both = [d1 d2];
                        both = both(both > 0 & both < 6000);
                        if isempty(both)
                            app.measured_values(n, 3) = 0;
                        else
                            app.measured_values(n, 3) = min(both);
                        end
                end
            end
            [X, Y, Z, P] = ReconstructHardware(app.measured_values);
            if ~isempty(X)
                mesh(app.UIAxes2, X, Y, Z);
            else
                scatter3(app.UIAxes2, P(:,1), P(:,2), P(:,3), 6, P(:,3), 'filled');
            end
            title(app.UIAxes2, sprintf('Measured Scan: %d points', size(P,1)));
         
        end
        
        function ShowLive(app)
            n_total = app.s.UserData.n;
            if n_total < 1
                return
            end
            try
                % Same angle conversion as ShowMeasured
                pan_rad  = deg2rad(app.s.UserData.pan_ang(1:n_total) - 90);
                tilt_rad = deg2rad(app.s.UserData.tilt_ang(1:n_total) - 100);
    
                if strcmp(app.sensorchoice, 'Ultrasonic')
                    d = app.s.UserData.distance2(1:n_total);
                else
                    d = app.s.UserData.distance(1:n_total);
                end
    
                keep = (d > 0) & (d < 6000);
                if ~any(keep)
                    return
                end
                [x, y, z] = sph2cart(pan_rad(keep), tilt_rad(keep), d(keep));
                scatter3(app.UIAxes2, x, y, z, 6, z, 'filled');
                title(app.UIAxes2, sprintf('Live scan: %d points', n_total));
                drawnow limitrate;
            catch err
                fprintf(2, 'ShowLive error: %s\n', err.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM8", 115200);
            configureTerminator(app.s, "CR/LF");
            pause(2);
            DisplayRoom(app);
        
            ud.pan_ang = zeros(1,1);
            ud.tilt_ang = zeros(1,1);
            ud.distance = zeros(1,1);
            ud.distance2 = zeros(1,1);
            ud.n = 0;
            app.s.UserData = ud;
            configureCallback(app.s, "terminator", @(obj, event) sensor_read(app, obj, event));
        end

        % Value changed function: XsizeEditField
        function XsizeEditFieldValueChanged(app, event)
            value = app.XsizeEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: YsizeEditField
        function YsizeEditFieldValueChanged(app, event)
            value = app.YsizeEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: RoomGridEditField
        function RoomGridEditFieldValueChanged(app, event)
            value = app.RoomGridEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: XStartEditField
        function XStartEditFieldValueChanged(app, event)
            value = app.XStartEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: XEndEditField
        function XEndEditFieldValueChanged(app, event)
            value = app.XEndEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: YStartEditField
        function YStartEditFieldValueChanged(app, event)
            value = app.YStartEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: YEndEditField
        function YEndEditFieldValueChanged(app, event)
            value = app.YEndEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: ConeAngleEditField
        function ConeAngleEditFieldValueChanged(app, event)
            value = app.ConeAngleEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: RaysEditField
        function RaysEditFieldValueChanged(app, event)
            value = app.RaysEditField.Value;
            DisplayRoom(app)
        end

        % Value changed function: LayersEditField
        function LayersEditFieldValueChanged(app, event)
            value = app.LayersEditField.Value;
            DisplayRoom(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            RunSim(app)
        end

        % Close request function: MatlabAppforLab6UIFigure
        function MatlabAppforLab6UIFigureCloseRequest(app, event)
            delete(app.s);
            delete(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.s.UserData.pan_ang = zeros(1,1);
            app.s.UserData.tilt_ang = zeros(1,1);
            app.s.UserData.distance = zeros(1,1);
            app.s.UserData.distance2 = zeros(1,1);
            app.s.UserData.n = 0;
            updateScanParams(app);
            writeline(app.s, 'r');   
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            writeline(app.s, 's');
            pause(0.3);              
            ShowMeasured(app);
        end

        % Value changed function: PanStartEditField
        function PanStartEditFieldValueChanged(app, event)
            value = app.PanStartEditField.Value;
            updateScanParams(app);
        end

        % Value changed function: PanStopEditField
        function PanStopEditFieldValueChanged(app, event)
            value = app.PanStopEditField.Value;
            updateScanParams(app);
        end

        % Value changed function: TiltStartEditField
        function TiltStartEditFieldValueChanged(app, event)
            value = app.TiltStartEditField.Value;
            updateScanParams(app);
        end

        % Value changed function: TiltStopEditField
        function TiltStopEditFieldValueChanged(app, event)
            value = app.TiltStopEditField.Value;
            updateScanParams(app);
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            updateScanParams(app);
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            value = app.DelayEditField.Value;
            updateScanParams(app);
        end

        % Button pushed function: SaveScanButton
        function SaveScanButtonPushed(app, event)
        if isempty(app.measured_values) || all(app.measured_values(:) == 0)
        uialert(app.MatlabAppforLab6UIFigure, 'No scan data to save.', 'Nothing to save');
            return
        end
        [file, path] = uiputfile('*.mat', 'Save scan data', 'scan.mat');
        figure(app.MatlabAppforLab6UIFigure);        % dialog opens behind the app
        if isequal(file, 0)
            return
        end
        measured_values = app.measured_values;
        save(fullfile(path, file), 'measured_values');        
        end

        % Button pushed function: LoadScanButton
        function LoadScanButtonPushed(app, event)
           [file, path] = uigetfile('*.mat', 'Load scan data');
           figure(app.MatlabAppforLab6UIFigure);
            if isequal(file, 0)
                return
            end
            loaded = load(fullfile(path, file));
            if ~isfield(loaded, 'measured_values')
                uialert(app.MatlabAppforLab6UIFigure, 'That file holds no scan data.', 'Wrong file');
                return
            end
            app.measured_values = loaded.measured_values;
            [X, Y, Z, P] = ReconstructHardware(app.measured_values);
            if ~isempty(X)
                mesh(app.UIAxes2, X, Y, Z);
            else
                scatter3(app.UIAxes2, P(:,1), P(:,2), P(:,3), 6, P(:,3), 'filled');
            end
            title(app.UIAxes2, sprintf('Loaded scan: %d points', size(P,1)));    
        end

        % Button pushed function: SaveSimButton
        function SaveSimButtonPushed(app, event)

            if isempty(app.sim_values) || all(app.sim_values(:) == 0)
                uialert(app.MatlabAppforLab6UIFigure, 'No simulation data to save.', 'Nothing to save');
                return
            end
            [file, path] = uiputfile('*.mat', 'Save simulation data', 'sim.mat');
            figure(app.MatlabAppforLab6UIFigure);
            if isequal(file, 0)
                return
            end
            sim_values = app.sim_values;
            save(fullfile(path, file), 'sim_values');
        end

        % Button pushed function: LoadSimButton
        function LoadSimButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Load sim data');
            figure(app.MatlabAppforLab6UIFigure);
            if isequal(file, 0)
                return
            end
            loaded = load(fullfile(path, file));
            if ~isfield(loaded, 'sim_values')
                uialert(app.MatlabAppforLab6UIFigure, 'That file holds no sim data.', 'Wrong file');
                return
            end
            app.sim_values = loaded.sim_values;
            [X, Y, Z] = ReconstructData(app.sim_values);
            if ~isempty(X)
                mesh(app.UIAxes2, X, Y, Z);
            end      
        end

        % Value changed function: SensorDropDown
        function SensorDropDownValueChanged(app, event)
            app.sensorchoice = app.SensorDropDown.Value;
             updateScanParams(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MatlabAppforLab6UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {842, 842};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {485, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabAppforLab6UIFigure = uifigure('Visible', 'off');
            app.MatlabAppforLab6UIFigure.Name = 'Matlab App for Lab6';
            app.MatlabAppforLab6UIFigure.CloseRequestFcn = createCallbackFcn(app, @MatlabAppforLab6UIFigureCloseRequest, true);
            app.MatlabAppforLab6UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MatlabAppforLab6UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.RoomTab = uitab(app.TabGroup);
            app.RoomTab.Title = 'Room';
            app.GridRoomPanel = uipanel(app.RoomTab);
            app.GridRoomPanel.Title = 'Grid Room';
            app.XsizeEditFieldLabel = uilabel(app.GridRoomPanel);
            app.XsizeEditFieldLabel.Text = 'X-size';
            app.XsizeEditField = uieditfield(app.GridRoomPanel, 'numeric');
            app.XsizeEditField.Limits = [2 Inf];
            app.XsizeEditField.ValueChangedFcn = createCallbackFcn(app, @XsizeEditFieldValueChanged, true);
            app.XsizeEditField.Value = 20;
            app.YsizeEditFieldLabel = uilabel(app.GridRoomPanel);
            app.YsizeEditFieldLabel.Text = 'Y-size';
            app.YsizeEditField = uieditfield(app.GridRoomPanel, 'numeric');
            app.YsizeEditField.Limits = [2 Inf];
            app.YsizeEditField.ValueChangedFcn = createCallbackFcn(app, @YsizeEditFieldValueChanged, true);
            app.YsizeEditField.Value = 30;
            app.RoomGridEditFieldLabel = uilabel(app.GridRoomPanel);
            app.RoomGridEditFieldLabel.Text = 'Room Grid';
            app.RoomGridEditField = uieditfield(app.GridRoomPanel, 'numeric');
            app.RoomGridEditField.Limits = [0.001 Inf];
            app.RoomGridEditField.ValueChangedFcn = createCallbackFcn(app, @RoomGridEditFieldValueChanged, true);
            app.RoomGridEditField.Value = 1;
            app.ObjectControlPanel = uipanel(app.RoomTab);
            app.ObjectControlPanel.Title = 'Object Control';
            app.XStartEditFieldLabel = uilabel(app.ObjectControlPanel);
            app.XStartEditFieldLabel.Text = 'X Start';
            app.XStartEditField = uieditfield(app.ObjectControlPanel, 'numeric');
            app.XStartEditField.Limits = [1 Inf];
            app.XStartEditField.RoundFractionalValues = 'on';
            app.XStartEditField.ValueChangedFcn = createCallbackFcn(app, @XStartEditFieldValueChanged, true);
            app.XStartEditField.Value = 7;
            app.XEndEditFieldLabel = uilabel(app.ObjectControlPanel);
            app.XEndEditFieldLabel.Text = 'X End';
            app.XEndEditField = uieditfield(app.ObjectControlPanel, 'numeric');
            app.XEndEditField.Limits = [1 Inf];
            app.XEndEditField.RoundFractionalValues = 'on';
            app.XEndEditField.ValueChangedFcn = createCallbackFcn(app, @XEndEditFieldValueChanged, true);
            app.XEndEditField.Value = 13;
            app.YStartEditFieldLabel = uilabel(app.ObjectControlPanel);
            app.YStartEditFieldLabel.Text = 'Y Start';
            app.YStartEditField = uieditfield(app.ObjectControlPanel, 'numeric');
            app.YStartEditField.Limits = [1 Inf];
            app.YStartEditField.RoundFractionalValues = 'on';
            app.YStartEditField.ValueChangedFcn = createCallbackFcn(app, @YStartEditFieldValueChanged, true);
            app.YStartEditField.Value = 10;
            app.YEndEditFieldLabel = uilabel(app.ObjectControlPanel);
            app.YEndEditFieldLabel.Text = 'Y End';
            app.YEndEditField = uieditfield(app.ObjectControlPanel, 'numeric');
            app.YEndEditField.Limits = [1 Inf];
            app.YEndEditField.RoundFractionalValues = 'on';
            app.YEndEditField.ValueChangedFcn = createCallbackFcn(app, @YEndEditFieldValueChanged, true);
            app.YEndEditField.Value = 20;
            app.HeightEditFieldLabel = uilabel(app.ObjectControlPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ObjectControlPanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 8;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationControlPanel = uipanel(app.SimulationTab);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.ConeAngleEditFieldLabel = uilabel(app.SimulationControlPanel);
            app.ConeAngleEditFieldLabel.Text = 'Cone Angle';
            app.ConeAngleEditField = uieditfield(app.SimulationControlPanel, 'numeric');
            app.ConeAngleEditField.Limits = [0 180];
            app.ConeAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ConeAngleEditFieldValueChanged, true);
            app.ConeAngleEditField.Value = 20;
            app.RaysEditFieldLabel = uilabel(app.SimulationControlPanel);
            app.RaysEditFieldLabel.Text = 'Rays';
            app.RaysEditField = uieditfield(app.SimulationControlPanel, 'numeric');
            app.RaysEditField.Limits = [1 Inf];
            app.RaysEditField.ValueChangedFcn = createCallbackFcn(app, @RaysEditFieldValueChanged, true);
            app.RaysEditField.Value = 40;
            app.LayersEditFieldLabel = uilabel(app.SimulationControlPanel);
            app.LayersEditFieldLabel.Text = 'Layers';
            app.LayersEditField = uieditfield(app.SimulationControlPanel, 'numeric');
            app.LayersEditField.Limits = [1 Inf];
            app.LayersEditField.ValueChangedFcn = createCallbackFcn(app, @LayersEditFieldValueChanged, true);
            app.LayersEditField.Value = 3;
            app.StartButton = uibutton(app.SimulationControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveSimButton = uibutton(app.SimulationTab, 'push');
            app.SaveSimButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSimButtonPushed, true);
            app.SaveSimButton.Text = 'Save Sim';
            app.LoadSimButton = uibutton(app.SimulationTab, 'push');
            app.LoadSimButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimButtonPushed, true);
            app.LoadSimButton.Text = 'Load Sim';
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.RunButton = uibutton(app.HardwareTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.HardwareTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ScanControlPanel = uipanel(app.HardwareTab);
            app.ScanControlPanel.Title = 'Scan Control';
            app.PanStartEditFieldLabel = uilabel(app.ScanControlPanel);
            app.PanStartEditFieldLabel.Text = 'Pan Start';
            app.PanStartEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.PanStartEditField.Limits = [10 170];
            app.PanStartEditField.RoundFractionalValues = 'on';
            app.PanStartEditField.ValueChangedFcn = createCallbackFcn(app, @PanStartEditFieldValueChanged, true);
            app.PanStartEditField.Value = 65;
            app.PanStopEditFieldLabel = uilabel(app.ScanControlPanel);
            app.PanStopEditFieldLabel.Text = 'Pan Stop';
            app.PanStopEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.PanStopEditField.Limits = [10 170];
            app.PanStopEditField.RoundFractionalValues = 'on';
            app.PanStopEditField.ValueChangedFcn = createCallbackFcn(app, @PanStopEditFieldValueChanged, true);
            app.PanStopEditField.Value = 115;
            app.TiltStartEditFieldLabel = uilabel(app.ScanControlPanel);
            app.TiltStartEditFieldLabel.Text = 'Tilt Start';
            app.TiltStartEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.TiltStartEditField.Limits = [60 140];
            app.TiltStartEditField.RoundFractionalValues = 'on';
            app.TiltStartEditField.ValueChangedFcn = createCallbackFcn(app, @TiltStartEditFieldValueChanged, true);
            app.TiltStartEditField.Value = 64;
            app.TiltStopEditFieldLabel = uilabel(app.ScanControlPanel);
            app.TiltStopEditFieldLabel.Text = 'Tilt Stop';
            app.TiltStopEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.TiltStopEditField.Limits = [60 140];
            app.TiltStopEditField.RoundFractionalValues = 'on';
            app.TiltStopEditField.ValueChangedFcn = createCallbackFcn(app, @TiltStopEditFieldValueChanged, true);
            app.TiltStopEditField.Value = 112;
            app.StepSizeEditFieldLabel = uilabel(app.ScanControlPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.RoundFractionalValues = 'on';
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 2;
            app.DelayEditFieldLabel = uilabel(app.ScanControlPanel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.DelayEditField.Limits = [0 Inf];
            app.DelayEditField.RoundFractionalValues = 'on';
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.DelayEditField.Value = 150;
            app.SaveScanButton = uibutton(app.HardwareTab, 'push');
            app.SaveScanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScanButtonPushed, true);
            app.SaveScanButton.Text = 'Save Scan';
            app.LoadScanButton = uibutton(app.HardwareTab, 'push');
            app.LoadScanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanButtonPushed, true);
            app.LoadScanButton.Text = 'Load Scan';
            app.SensorDropDownLabel = uilabel(app.HardwareTab);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.HardwareTab);
            app.SensorDropDown.Items = {'ToF', 'Ultrasonic', 'Both'};
            app.SensorDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorDropDownValueChanged, true);
            app.SensorDropDown.Value = 'ToF';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Simulation Room')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Simulated Reconstruction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.MatlabAppforLab6UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Demo_code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabAppforLab6UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabAppforLab6UIFigure)
        end
    end
end